classdef SuperSpectrogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ComputeParametersButton         matlab.ui.control.Button
        minFreqHzEditFieldLabel         matlab.ui.control.Label
        minFreqHzEditField              matlab.ui.control.NumericEditField
        maxFreqHzEditFieldLabel         matlab.ui.control.Label
        maxFreqHzEditField              matlab.ui.control.NumericEditField
        UpdateDiagramButton             matlab.ui.control.Button
        DecimationFactorDropDownLabel   matlab.ui.control.Label
        DecimationFactorDropDown        matlab.ui.control.DropDown
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        SamplingFreqHzEditFieldLabel    matlab.ui.control.Label
        SamplingFreqHzEditField         matlab.ui.control.EditField
        HighestNoteEditFieldLabel       matlab.ui.control.Label
        HighestNoteEditField            matlab.ui.control.EditField
        SpectreThresholdEditFieldLabel  matlab.ui.control.Label
        SpectreThresholdEditField       matlab.ui.control.NumericEditField
        SelectFileButton                matlab.ui.control.Button
        FileLocationLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        A;
        Lold
        Gv
        Fs
        filename
        fs
         % Description
    end
    
    methods (Access = private)
        
        function Gv = STFT(app, sig,sigma)
            wd = 16*sigma; %Define the window size based on the value of the sigma
            wdd2 = wd/2;    %Fixed, necessary for removing the edge effects
            wdd8 = wd/8;    %Fixed, don't change
            n = wd/32;      %Length of the window necessary for remove the edge effects (function of Variance)
            ns = ceil(length(sig)/wd); %Number of windows necessary to process all the signal length
            
            for k = 1:ns %For each window, compute a new STFT
               wSig(1:wd,1) = sig(1,(k-1)*wd+1:(k-1)*wd + wd); %wSig is the windowed signal
               TMP = stftcp(app,wSig,sigma);   
               TF2(:,(k-1)*wdd8+1:(k-1)*wdd8+wdd8) = TMP(:,1:(wd/wdd8):wd);
            end
            
            TF = TF2;   %Attribute the computed STFT to the vector TF
            TF1 = zeros(size(TF2)); %Initialize a new vector TF1
            for k = 1:ns-1 % Shift the original window and compute a new STFT in order to remove the edge effects
                wSig(1:wd,1) = sig(1,(k-1)*wd + 1 + wdd2:(k-1)*wd + wd + wdd2);
                TMP = stftcp(app,wSig,sigma);   
                TF1(:,(k-1)*wdd8+1:(k-1)*wdd8+wdd8) = TMP(:,1:(wd/wdd8):wd);
            end
            
            for k = 1:ns-1  % Merge the TF1 inside TF for removing the edge effects
               TF(:,k*wdd8-n:k*wdd8+n) = TF1(:,(k-1)*wdd8 + wdd8/2 - n: (k-1)*wdd8 + wdd8/2 + n); 
            end
            
            
            %% Representing the STFT graphically
            
            Fv = 20*log10(fftshift(abs(TF),1)); %Convert the amplitude of the TF matrix to dB
            Lthr = app.SpectreThresholdEditField.Value;
            Uthr = 0;  %Define the upper bound for the threshold (dB)
            Fv(Fv < Lthr | Fv > Uthr) = -Inf; % Set the undesired indexes to -Inf
            Gv = Fv(size(Fv,1)/2 + 1:size(Fv,1),1:size(Fv,2));
        end
        
        function TF = stftcp(app,f,wd)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % short-time Fourier transform with Guassian window
            %
            % Input:
            %      f - signal (real or complex)
            %      wd - std. deviation (sigma) of the Gaussian function
            % Output:
            %      TF - time-frequency distribution
            %
            % By V.C. Chen
            %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            cntr = length(f)/2;
            sigma = wd;
            fsz = length(f);
            z = exp(-([1:fsz]-fsz/2).^2/(2*sigma^2))/(sqrt(2*pi)*sigma);
            for m=1:fsz
                mm = m-cntr+fsz/2;
                if (mm <= fsz && mm >= 1)
                    gwin(m) = z(mm);
                else
                    if (mm > fsz)
                       gwin(m) = z(rem(mm,fsz));
                    else
                       if(mm < 1)
                          mm1 = mm+fsz;
                          gwin(m) = z(mm1);
                       end
                    end
                end
            end
            winsz = length(gwin);
            x = zeros(1,fsz+winsz); % zero padding
            x(winsz/2+1:winsz/2+fsz) = f;
            for j = 1:fsz
               for k = 1:winsz
                  X(k) = gwin(k)*x(j+k);  
               end             
               TF(:,j) = fft(X).';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeParametersButton
        function ComputeParametersButtonPushed(app, event)
            app.StatusEditField.Value = 'Computing';
            cla(app.UIAxes);
         
            %filename = 'C:\Users\Charles\Documents\MATLAB\Wav Files\Test.wav';
            [x,app.fs] = audioread(app.filename);
            
            %len = length(x)/fs;
            
            DecV = app.DecimationFactorDropDown.Value; % Decimation Factor
            switch DecV
                case '5'
                    l = 8; % Define the level of detail of the STFT
                    Dec = 5;         
                case '10'
                    l = 7;
                    Dec = 10;
                case '20'
                    l = 6;
                    Dec = 20;
                case '40'
                    l = 5;
                    Dec = 40;
                otherwise
                    l = 7;
                    Dec = 10;
            end
            
            % The Decimation Factor impacts the highest visible frequency:
            % According to Nyquist Theorem: fmax = fs/(2*Dec)
            % It means, if fs = 44.1 kHz, fmax = 2250 Hz
            % However, it also impacts the processing speed
            
            if size(x,2) == 2
                P = x(:,1) + x(:,2); % Comment this line if the file is mono
            else
                P = x(:,1);
            end
            y = decimate(P,Dec); %Convert from stereo to mono
            app.Fs = app.fs/Dec; %New sampling frequency
            app.SamplingFreqHzEditField.Value = num2str(app.Fs);
            app.maxFreqHzEditField.Limits = [app.minFreqHzEditField.Value,floor(app.Fs/2)];
            app.minFreqHzEditField.Limits = [0,app.maxFreqHzEditField.Value];
            
            %% STFT Computation
                
            sigma =2^l; % Compute sigma
            
            app.Lold = length(y);
            
            %% New Method
            
            st = 0; %stop condition
            Lr = app.Lold;
            app.A = [0];
            while st ~= 1
               app.A = [app.A, 2^(nextpow2(Lr)-1)];
               Lr = Lr - 2^(nextpow2(Lr)-1);
               if Lr <= 2^(l+4)
                   st = 1;
                   app.A = [app.A,2^(l+4)];
               end
            end
            %Idea: Create a sliding window to compensate for the window border effect
            y = [y; zeros(sum(app.A)-app.Lold,1)]; % Complete y with zeros
            app.Gv = zeros(8*sigma,sum(app.A)/8); % Initialize Gv
            for i = 2:length(app.A)
                sig = y(sum(app.A(1:i-1)) + 1 : sum(app.A(1:i)));
                app.Gv(:,sum(app.A(1:i-1))/8 + 1:sum(app.A(1:i))/8) = app.STFT(sig',sigma); % Compute the STFT
            end
            
            for i = 2:length(app.A)-1
                sig = y(sum(app.A(1:i)) - 2^(l+3)+1 : sum(app.A(1:i)) + 2^(l+3));
                Gi = app.STFT(sig',sigma);
                app.Gv(:,sum(app.A(1:i))/8 - 2^(l-1)+1:sum(app.A(1:i))/8 + 2^(l-1)) = Gi(:,2^(l-1)+1:2^l + 2^(l-1)); % Compute the STFT
            end
            app.StatusEditField.Value = 'Computed';
            app.UpdateDiagramButtonPushed;
            app.UpdateDiagramButton.Enable = "on";
        end

        % Button pushed function: UpdateDiagramButton
        function UpdateDiagramButtonPushed(app, event)
            cla(app.UIAxes);
            %% STFT Display %%
            fmin = app.minFreqHzEditField.Value; % Define the minimum frequency to display 
            fmax = app.maxFreqHzEditField.Value; % Define the maximum frequency to display
            
            t = 1/app.Fs:1/app.Fs:app.Lold/app.Fs; % Compute the time instants 
            
            lenSTFT = sum(app.A);%2^(nextpow2(Lold)); % Compute the length of the STFT
            occSpace = ceil(app.Lold*size(app.Gv,2)/lenSTFT); % Compute the number of rows effectively used by the STFT
            f = linspace(0,app.Fs/2,size(app.Gv,1)); % Compute the frequency axis values 
            ifmin = find(abs(fmin-f') == min(abs(fmin-f'))); % Compute the index related to the minimum frequency
            ifmax = find(abs(fmax-f') == min(abs(fmax-f'))); % Compute the index related to the maximum frequency

            colormap(app.UIAxes, jet(256)) % Define the color scale (follow a standard)
            imagesc(app.UIAxes,[t(1),t(end)],[f(ifmin), f(ifmax)],app.Gv(ifmin:ifmax, 1:occSpace));   %Graphical representation of the STFT:
            %colorbar;   % Displays a color bar in the right side of the figure
            title(app.UIAxes,'Short-Time Fourier Transform Representation of the Signal'); %Define the title for the figure
            xlabel(app.UIAxes,'Time (s)'); % Define the label for the x-axis of the figure
            ylabel(app.UIAxes,'Frequency (Hz)');   %Define the label for the y-axis of the figure
            hold(app.UIAxes,"on"); % Hold the plot
            notes = ["sil";...
                                                   "C0";"C#0";"D0";"Eb0";"E0";"F0"; "F#0";...
                     "G0"; "Ab0"; "A0"; "Bb0";"B0";"C1";"C#1";"D1";"Eb1";"E1";"F1"; "#F#1";...
                     "G1"; "Ab1"; "A1"; "Bb1";"B1";"C2";"C#2";"D2";"Eb2";"E2";"F2"; "F#2";...
                     "G2"; "Ab2"; "A2"; "Bb2";"B2";"C3";"C#3";"D3";"Eb3";"E3";"F3"; "F#3";...
                     "G3"; "Ab3"; "A3"; "Bb3";"B3";"C4";"C#4";"D4";"Eb4";"E4";"F4"; "F#4";...
                     "G4"; "Ab4"; "A4"; "Bb4";"B4";"C5";"C#5";"D5";"Eb5";"E5";"F5"; "F#5";...
                     "G5"; "Ab5"; "A5"; "Bb5";"B5";"C6";"C#6";"D6";"Eb6";"E6";"F6"; "F#6"; ...
                     "G6"; "Ab6"; "A6"; "Bb6";"B6";"C7";"C#7";"D7";"Eb7";"E7";"F7"; "F#7";...
                     "G7"; "Ab7"; "A7"; "Bb7";"B7";"C8";"C#8";"D8";"Eb8";"E8";"F8"; "F#8";...
                     "G8"; "Ab8"; "A8"; "Bb8";"B8"]; % Define the names of the musical notes
                 %1       1        1           1        1        2        2          2 
            fnotes = [0;...
                                                                16.35  ; 17.32   ; 18.35   ; 19.45  ; 20.60  ; 21.83   ; 23.12   ; ...
                24.50  ; 25.96   ; 27.50  ; 29.14   ; 30.87   ; 32.7   ; 34.65   ; 36.71   ; 38.89  ; 41.20  ; 43.65   ; 46.25   ; ...
                49     ; 51.91   ; 55     ; 58.27   ; 61.74   ; 65.41  ; 69.3    ; 73.42   ; 77.78  ; 82.41  ; 87.31   ; 92.5    ; ... 
                98     ; 103.83  ; 110    ; 116.54  ; 123.47  ; 130.81 ; 138.59  ; 146.83  ; 155.56 ; 164.81 ; 174.61  ; 185     ; ...
                196.00 ; 207.65  ; 220.00 ; 233.08  ; 246.94  ; 261.63 ; 277.18  ; 293.66  ; 311.13 ; 329.63 ; 349.23  ; 369.99  ; ...
                392.00 ; 415.30  ; 440.00 ; 466.16  ; 493.88  ; 523.25 ; 554.37  ;  587.33 ; 622.25 ; 659.25 ; 698.46  ; 739.99  ; ...
                783.99 ; 830.61  ; 880.00 ; 932.33  ; 987.77  ; 1046.50; 1108.73 ; 1174.66 ; 1244.51; 1318.51; 1396.91 ; 1479.98 ; ...
                1567.98 ; 1661.22; 1760.00; 1864.66 ; 1975.53; 2093.00 ; 2217.46 ; 2349.32; 2489.02 ; 2637.02 ; 2793.83; 2959.96 ;...
                3135.96 ; 3322.44; 3520.00; 3729.31 ; 3951.07; 4186.01 ; 4434.92 ; 4698.63; 4978.03 ; 5274.04 ; 5587.65; 5919.91 ;...
                6271.93 ; 6644.88; 7040.00; 7458.62; 7902.13];
            %     G        Ab        A        Bb        B        C          C#       D         Eb       E         F         F#
            k1 = f(ifmin);
            ind1 = find(abs(k1-fnotes') == min(abs(k1-fnotes')));
            k2 = f(ifmax);
            ind2 = find(abs(k2-fnotes') == min(abs(k2-fnotes')));
            
            for i = ind1:ind2
                s = plot(app.UIAxes, linspace(0,t(end),10), fnotes(i)*ones(1, 10), '-w');
                dtt = s.DataTipTemplate; % Get the DataTip information of this plot
                dtt.DataTipRows(1).Label = 'Time'; % Define the first label as Time
                dtt.DataTipRows(2).Label = 'Freq'; % Define the second label as Frequency
                dtt.DataTipRows(3).Label = 'Note'; % Create a third DataTip called 'Note'
                dtt.DataTipRows(3).Value = repmat(notes(i),1,10); % Attribute the correct note to that DataTip
            end
            yticks(app.UIAxes, fnotes(ind1:ind2));
            yticklabels(app.UIAxes, notes(ind1:ind2));
            axis(app.UIAxes, [t(1),t(end), fmin, fmax]);
            
            %% Update the maximum Note
            Dec = str2double(app.DecimationFactorDropDown.Value); % Decimation Factor
            mFreq = floor(app.fs/(2*Dec)); %Maximum Frequency Displayed in the Diagram
            indmax = find(abs(mFreq-fnotes') == min(abs(mFreq-fnotes')));
            app.HighestNoteEditField.Value = notes(indmax);
        end

        % Value changed function: DecimationFactorDropDown
        function DecimationFactorDropDownValueChanged(app, event)
            value = app.DecimationFactorDropDown.Value;
            app.StatusEditField.Value = 'Not Computed';
        end

        % Value changed function: minFreqHzEditField
        function minFreqHzEditFieldValueChanged(app, event)
            value = app.minFreqHzEditField.Value;
            app.maxFreqHzEditField.Limits = [app.minFreqHzEditField.Value,floor(app.Fs/2)];
            app.minFreqHzEditField.Limits = [0,app.maxFreqHzEditField.Value];
        end

        % Value changed function: maxFreqHzEditField
        function maxFreqHzEditFieldValueChanged(app, event)
            value = app.maxFreqHzEditField.Value;
            app.maxFreqHzEditField.Limits = [app.minFreqHzEditField.Value,floor(app.Fs/2)];
            app.minFreqHzEditField.Limits = [0,app.maxFreqHzEditField.Value];
        end

        % Value changed function: SpectreThresholdEditField
        function SpectreThresholdEditFieldValueChanged(app, event)
            value = app.SpectreThresholdEditField.Value;
            app.StatusEditField.Value = 'Not Computed';
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
               %disp('User selected Cancel');
               app.StatusEditField.Value = 'Press Select File';
            else
               %disp(['User selected ', fullfile(path,file)]);
               app.filename = fullfile(path,file);
               app.ComputeParametersButton.Enable = "on";
               app.StatusEditField.Value = 'File Selected';
               app.FileLocationLabel.Text = ['File Location: ', app.filename];
               app.FileLocationLabel.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ComputeParametersButton = uibutton(app.UIFigure, 'push');
            app.ComputeParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeParametersButtonPushed, true);
            app.ComputeParametersButton.Enable = 'off';
            app.ComputeParametersButton.Text = 'Compute Parameters';
            app.minFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.minFreqHzEditFieldLabel.Text = 'min Freq (Hz)';
            app.minFreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.minFreqHzEditField.Limits = [0 4410];
            app.minFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @minFreqHzEditFieldValueChanged, true);
            app.minFreqHzEditField.Value = 100;
            app.maxFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.maxFreqHzEditFieldLabel.Text = 'max Freq (Hz)';
            app.maxFreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxFreqHzEditField.Limits = [0 4410];
            app.maxFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @maxFreqHzEditFieldValueChanged, true);
            app.maxFreqHzEditField.Value = 300;
            app.UpdateDiagramButton = uibutton(app.UIFigure, 'push');
            app.UpdateDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDiagramButtonPushed, true);
            app.UpdateDiagramButton.Enable = 'off';
            app.UpdateDiagramButton.Text = 'Update Diagram';
            app.DecimationFactorDropDownLabel = uilabel(app.UIFigure);
            app.DecimationFactorDropDownLabel.Text = 'Decimation Factor';
            app.DecimationFactorDropDown = uidropdown(app.UIFigure);
            app.DecimationFactorDropDown.Items = {'5', '10', '20', '40'};
            app.DecimationFactorDropDown.ValueChangedFcn = createCallbackFcn(app, @DecimationFactorDropDownValueChanged, true);
            app.DecimationFactorDropDown.Value = '10';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Press Select File';
            app.SamplingFreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq (Hz)';
            app.SamplingFreqHzEditField = uieditfield(app.UIFigure, 'text');
            app.SamplingFreqHzEditField.Editable = 'off';
            app.SamplingFreqHzEditField.Value = 'Unknown';
            app.HighestNoteEditFieldLabel = uilabel(app.UIFigure);
            app.HighestNoteEditFieldLabel.Text = 'Highest Note';
            app.HighestNoteEditField = uieditfield(app.UIFigure, 'text');
            app.HighestNoteEditField.Editable = 'off';
            app.HighestNoteEditField.Value = '-';
            app.SpectreThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.SpectreThresholdEditFieldLabel.Text = 'Spectre Threshold';
            app.SpectreThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpectreThresholdEditField.Limits = [-Inf 0];
            app.SpectreThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SpectreThresholdEditFieldValueChanged, true);
            app.SpectreThresholdEditField.Value = -70;
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FileLocationLabel = uilabel(app.UIFigure);
            app.FileLocationLabel.Visible = 'off';
            app.FileLocationLabel.Text = 'File Location: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuperSpectrogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end